classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        PredictionLabel  matlab.ui.control.Label
        Image            matlab.ui.control.Image
        Button           matlab.ui.control.Button
    end

    
    properties (Access = private)
        net
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Load trained CNN
try
    load("mymodel.mat", 'net');  % assumes cnnModel.mat contains variable 'net'
    app.net = net;
catch
    uialert(app.UIFigure, 'CNN model could not be loaded.', 'Error');
end

        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
      [file, path] = uigetfile({'*.png;*.jpg;*.jpeg','Image Files (*.png, *.jpg, *.jpeg)'});
    if isequal(file, 0)
        disp('User canceled file selection');
        return;
    end

    fullFileName = fullfile(path, file);  % Construct full path
    img = imread(fullFileName);           % Read image

    % Ensure image is RGB and of supported type
    if size(img, 3) ~= 3
        img = repmat(img, [1 1 3]);       % Convert grayscale to RGB
    end
   if ~isa(img, 'uint8')
    img = im2uint8(img);
end

    % Display image in App
    app.Image.ImageSource = img;

    % Resize and preprocess for model prediction
    resizedImg = imresize(img, [83 226]);
    resizedImg = double(resizedImg);     % Convert to double if needed
    if isa(app.net.Layers(1), 'nnet.cnn.layer.ImageInputLayer') && ...
       app.net.Layers(1).InputSize(3) == 1
        resizedImg = rgb2gray(resizedImg);      % If model expects grayscale
    end

    % Classify
    prediction = classify(app.net, resizedImg);

    % Show result
    uialert(app.UIFigure, sprintf("Prediction: %s", string(prediction)), "Result");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'dataset1', 'forge', '02100002.png');
            app.PredictionLabel = uilabel(app.UIFigure);
            app.PredictionLabel.Text = 'Prediction';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end